classdef r3d3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        R3D3UIFigure                   matlab.ui.Figure
        EndEffectorPositionPanel       matlab.ui.container.Panel
        zcoordinateEditFieldLabel      matlab.ui.control.Label
        zcoordinateEditField           matlab.ui.control.NumericEditField
        ycoordinateEditFieldLabel      matlab.ui.control.Label
        ycoordinateEditField           matlab.ui.control.NumericEditField
        xcoordinateEditFieldLabel      matlab.ui.control.Label
        xcoordinateEditField           matlab.ui.control.NumericEditField
        JointAnglesPanel               matlab.ui.container.Panel
        q6EditFieldLabel               matlab.ui.control.Label
        q6EditField                    matlab.ui.control.NumericEditField
        q3EditFieldLabel               matlab.ui.control.Label
        q3EditField                    matlab.ui.control.NumericEditField
        q5EditFieldLabel               matlab.ui.control.Label
        q5EditField                    matlab.ui.control.NumericEditField
        q4EditFieldLabel               matlab.ui.control.Label
        q4EditField                    matlab.ui.control.NumericEditField
        q2EditFieldLabel               matlab.ui.control.Label
        q2EditField                    matlab.ui.control.NumericEditField
        q1EditFieldLabel               matlab.ui.control.Label
        q1EditField                    matlab.ui.control.NumericEditField
        angles_plot                    matlab.ui.control.UIAxes
        velocities_plot                matlab.ui.control.UIAxes
        OrientationPanel               matlab.ui.container.Panel
        PointtowardsEditFieldLabel     matlab.ui.control.Label
        PointtowardsEditField          matlab.ui.control.EditField
        GripperrotationEditFieldLabel  matlab.ui.control.Label
        GripperrotationEditField       matlab.ui.control.EditField
        OptionsPanel                   matlab.ui.container.Panel
        VelocitySliderLabel            matlab.ui.control.Label
        VelocitySlider                 matlab.ui.control.Slider
        TaskDropDownLabel              matlab.ui.control.Label
        TaskDropDown                   matlab.ui.control.DropDown
        UploadButton                   matlab.ui.control.Button
        EasingSliderLabel              matlab.ui.control.Label
        EasingSlider                   matlab.ui.control.Slider
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        StartButton                    matlab.ui.control.Button
        UpDownConfig                   matlab.ui.control.RockerSwitch
        ExecuteButton                  matlab.ui.control.Button
        ToolsTab                       matlab.ui.container.Tab
        ForceUpdateButton              matlab.ui.control.Button
        OpenModelButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public, SetObservable)
        mode = 'inverse';
        model; % top level model name
        modelPathl; % full path to the model
        isRunning; % model is running
        targetPosition; % Target Position
        currentPosition;
        q; %joint angles
        count = 0;
        udpr; % UDP Receiver object
        udps; % UDP Sender object
        trajectory;
        n = 1000;
    end
    
    methods (Access = public)
        
        function traj = GetTrajectory(app)
            traj = lin_trajectory(app.currentPosition, ... 
                app.targetPosition, ... 
                app.n);
            
            % Also store it as a property
            app.trajectory = traj;
        end
        
    end
    
    methods (Access = private)
        
        % Configures the model with the correct callbacks
        function ConfigureModel(app)
            load_system(app.model);
            set_param(app.model,'PostLoadFcn','cd(fileparts(which(bdroot)))')
            set_param(app.model,'InitFcn','addpath(genpath(''../''));arm_datafile');
            save_system(app.model);
        end
        
        function StartSimulation(app,event)
            set_param(app.model, 'SimulationCommand', 'start');
        end
        
        function StopSimulation(app)
            set_param(app.model, 'SimulationCommand', 'stop');
        end
        
        function pos = GetTargetPosition(app)
            pos = [(app.xcoordinateEditField.Value);
                (app.ycoordinateEditField.Value);
                (app.zcoordinateEditField.Value);].';
            
        end
        
        function WriteJointAnglesToFields(app)
            
        end
        
        function OpenUDPSocket(app)
           app.udpr = dsp.UDPReceiver('LocalIPPort',31000);
           app.udps = dsp.UDPSender('RemoteIPPort',31000); 
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            OpenUDPSocket(app);
            
            app.currentPosition = GetTargetPosition(app);
            % Listen to changes to the joint angle property of this GUI.
            % These changes are propagated by the simulink model by
            % means of an S-function. We set a listener to the event and
            % bind a callback here.
            % addlistener(app,'q','PostSet',@(prop,event) app.WriteJointAnglesToFields);
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            app.targetPosition = GetTargetPosition(app);
            
            if(strcmp(app.mode,'forward'))
                
            else
                
            end
            
        end

        % Button pushed function: OpenModelButton
        function OpenModelButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.slx','Select the robot arm model');
            
            if isequal(filename,0)
                disp('User selected Cancel')
            else
                % Only open the model if the user actually selected a file
                disp(['User selected ', fullfile(pathname, filename)])
                app.modelPath = fullfile(pathname, filename);
                cd(pathname);
                open_system(app.modelPath);
                
                % Store top level model name
                app.model = bdroot;
                
                % Configure the model once its opened
                ConfigureModel(app);
            end
            
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            if(app.isRunning)
                app.StartButton.Text = 'Start';
                StopSimulation(app);
                app.isRunning = false;
                app.ExecuteButton.Enable = 'off';
                return;
            end
            
            % Open the model if this is the first time we're running
            if(isempty(app.model))
                
                % Open the model
                OpenModelButtonPushed(app,event);
                
            end
            
            % Not sure why this is inside if-statement
            if(exist(app.modelPath,'file'))
                
                app.targetPosition = GetTargetPosition(app);
                
                WriteToModelWorkspace(app);
                StartSimulation(app);
                ExecuteButtonPushed(app,event);
                app.isRunning = true;
            end
            
            % Change button state
            if(app.isRunning)
                app.StartButton.Text = 'Stop';
                app.ExecuteButton.Enable = 'on';
            else
                % Redundant
                app.isRunning = false;
                app.StartButton.Text = 'Start';
            end
            
        
        end

        % Value changed function: UpDownConfig
        function UpDownConfigValueChanged(app, event)
            app.mode = lower(app.UpDownConfig.Value);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.R3D3UIFigure = uifigure;
            app.R3D3UIFigure.Name = 'R3D3';
            app.EndEffectorPositionPanel = uipanel(app.R3D3UIFigure);
            app.EndEffectorPositionPanel.Title = 'End Effector Position';
            app.zcoordinateEditFieldLabel = uilabel(app.EndEffectorPositionPanel);
            app.zcoordinateEditFieldLabel.Text = 'z-coordinate';
            app.zcoordinateEditField = uieditfield(app.EndEffectorPositionPanel, 'numeric');
            app.ycoordinateEditFieldLabel = uilabel(app.EndEffectorPositionPanel);
            app.ycoordinateEditFieldLabel.Text = 'y-coordinate';
            app.ycoordinateEditField = uieditfield(app.EndEffectorPositionPanel, 'numeric');
            app.xcoordinateEditFieldLabel = uilabel(app.EndEffectorPositionPanel);
            app.xcoordinateEditFieldLabel.Text = 'x-coordinate';
            app.xcoordinateEditField = uieditfield(app.EndEffectorPositionPanel, 'numeric');
            app.JointAnglesPanel = uipanel(app.R3D3UIFigure);
            app.JointAnglesPanel.Title = 'Joint Angles';
            app.q6EditFieldLabel = uilabel(app.JointAnglesPanel);
            app.q6EditFieldLabel.Text = 'q6';
            app.q6EditField = uieditfield(app.JointAnglesPanel, 'numeric');
            app.q6EditField.ValueDisplayFormat = '%.3f';
            app.q6EditField.Editable = 'off';
            app.q3EditFieldLabel = uilabel(app.JointAnglesPanel);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.JointAnglesPanel, 'numeric');
            app.q3EditField.ValueDisplayFormat = '%.3f';
            app.q3EditField.Editable = 'off';
            app.q5EditFieldLabel = uilabel(app.JointAnglesPanel);
            app.q5EditFieldLabel.Text = 'q5';
            app.q5EditField = uieditfield(app.JointAnglesPanel, 'numeric');
            app.q5EditField.ValueDisplayFormat = '%.3f';
            app.q5EditField.Editable = 'off';
            app.q4EditFieldLabel = uilabel(app.JointAnglesPanel);
            app.q4EditFieldLabel.Text = 'q4';
            app.q4EditField = uieditfield(app.JointAnglesPanel, 'numeric');
            app.q4EditField.ValueDisplayFormat = '%.3f';
            app.q4EditField.Editable = 'off';
            app.q2EditFieldLabel = uilabel(app.JointAnglesPanel);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.JointAnglesPanel, 'numeric');
            app.q2EditField.ValueDisplayFormat = '%.3f';
            app.q2EditField.Editable = 'off';
            app.q1EditFieldLabel = uilabel(app.JointAnglesPanel);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.JointAnglesPanel, 'numeric');
            app.q1EditField.ValueDisplayFormat = '%.3f';
            app.q1EditField.Editable = 'off';
            app.angles_plot = uiaxes(app.R3D3UIFigure);
            title(app.angles_plot, 'Joint Angles')
            xlabel(app.angles_plot, 't')
            ylabel(app.angles_plot, '\theta [rad]')
            app.angles_plot.XLim = [0 1];
            app.angles_plot.XLimMode = 'manual';
            app.angles_plot.YLim = [0 1];
            app.angles_plot.YLimMode = 'manual';
            app.angles_plot.CLim = [0 1];
            app.angles_plot.CLimMode = 'manual';
            app.angles_plot.XTick = [0 0.5 1];
            app.angles_plot.YTick = [0 0.5 1];
            app.velocities_plot = uiaxes(app.R3D3UIFigure);
            title(app.velocities_plot, 'Joint Velocities')
            xlabel(app.velocities_plot, 't')
            ylabel(app.velocities_plot, 'd-theta [rad/s]')
            app.velocities_plot.XLim = [0 1];
            app.velocities_plot.XLimMode = 'manual';
            app.velocities_plot.YLim = [0 1];
            app.velocities_plot.YLimMode = 'manual';
            app.velocities_plot.CLim = [0 1];
            app.velocities_plot.CLimMode = 'manual';
            app.velocities_plot.XTick = [0 0.5 1];
            app.velocities_plot.YTick = [0 0.5 1];
            app.OrientationPanel = uipanel(app.R3D3UIFigure);
            app.OrientationPanel.Title = 'Orientation';
            app.PointtowardsEditFieldLabel = uilabel(app.OrientationPanel);
            app.PointtowardsEditFieldLabel.Text = 'Point towards';
            app.PointtowardsEditField = uieditfield(app.OrientationPanel, 'text');
            app.PointtowardsEditField.Value = '[ 1 1 1 ]';
            app.GripperrotationEditFieldLabel = uilabel(app.OrientationPanel);
            app.GripperrotationEditFieldLabel.Text = 'Gripper rotation';
            app.GripperrotationEditField = uieditfield(app.OrientationPanel, 'text');
            app.GripperrotationEditField.Value = '90 deg';
            app.OptionsPanel = uipanel(app.R3D3UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.VelocitySliderLabel = uilabel(app.OptionsPanel);
            app.VelocitySliderLabel.Text = 'Velocity';
            app.VelocitySlider = uislider(app.OptionsPanel);
            app.VelocitySlider.Limits = [0 10];
            app.VelocitySlider.Value = 5;
            app.TaskDropDownLabel = uilabel(app.OptionsPanel);
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.OptionsPanel);
            app.TaskDropDown.Items = {'Random 8', 'Spline 1', 'Spline 2', 'Fixed 8'};
            app.TaskDropDown.Value = 'Fixed 8';
            app.UploadButton = uibutton(app.OptionsPanel, 'push');
            app.UploadButton.Text = 'Upload';
            app.EasingSliderLabel = uilabel(app.OptionsPanel);
            app.EasingSliderLabel.Text = 'Easing';
            app.EasingSlider = uislider(app.OptionsPanel);
            app.EasingSlider.Limits = [0 10];
            app.EasingSlider.Value = 5;
            app.TabGroup = uitabgroup(app.R3D3UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.StartButton = uibutton(app.MainTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.UpDownConfig = uiswitch(app.MainTab, 'rocker');
            app.UpDownConfig.Items = {'Inverse', 'Forward'};
            app.UpDownConfig.ValueChangedFcn = createCallbackFcn(app, @UpDownConfigValueChanged, true);
            app.UpDownConfig.Value = 'Inverse';
            app.ExecuteButton = uibutton(app.MainTab, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.ForceUpdateButton = uibutton(app.ToolsTab, 'push');
            app.ForceUpdateButton.Text = 'Force Update';
            app.OpenModelButton = uibutton(app.ToolsTab, 'push');
            app.OpenModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenModelButtonPushed, true);
            app.OpenModelButton.Text = 'Open Model';
        end
    end

    methods (Access = public)

        % Construct app
        function app = r3d3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.R3D3UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.R3D3UIFigure)
        end
    end
end